classdef DemoGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SOPUIFigure           matlab.ui.Figure
        InputLabel            matlab.ui.control.Label
        FilePathLabel         matlab.ui.control.Label
        showFilePath          matlab.ui.control.EditField
        selectFile            matlab.ui.control.Button
        SignalPara            matlab.ui.control.Label
        unit                  matlab.ui.control.DropDown
        PlotPara              matlab.ui.control.Label
        TimeUnitselect        matlab.ui.control.DropDown
        FreUint               matlab.ui.control.Label
        Timeunit              matlab.ui.control.Label
        PlotLabel             matlab.ui.control.Label
        TimePlot              matlab.ui.control.UIAxes
        FrePlot               matlab.ui.control.UIAxes
        OutputLabel           matlab.ui.control.Label
        Modulation            matlab.ui.control.Label
        SignalFre             matlab.ui.control.Label
        SymbolRate            matlab.ui.control.Label
        SpreadingCode         matlab.ui.control.Label
        SampleRateLabel       matlab.ui.control.Label
        SampleRate            matlab.ui.control.NumericEditField
        FFTEditFieldLabel     matlab.ui.control.Label
        inputFFTPoint         matlab.ui.control.NumericEditField
        selectDataFormat      matlab.ui.control.DropDown
        DataFormatLabel       matlab.ui.control.Label
        StopButton            matlab.ui.control.Button
        StartButton           matlab.ui.control.Button
        ButtonGroup           matlab.ui.container.ButtonGroup
        ZoomIn                matlab.ui.control.StateButton
        SaveButton            matlab.ui.control.Button
        SymbolRateField       matlab.ui.control.NumericEditField
        SymbolRateUnit        matlab.ui.control.Label
        SigFreField           matlab.ui.control.NumericEditField
        SigFreUnit            matlab.ui.control.Label
        ModulationText        matlab.ui.control.Label
        SpreadingCodePlotAxe  matlab.ui.control.UIAxes
        ConsoleLabel          matlab.ui.control.Label
        SymbolCycleLabel      matlab.ui.control.Label
        SymbolCycleEditField  matlab.ui.control.NumericEditField
        PNCodeText            matlab.ui.control.TextArea
        ConsoleEditField      matlab.ui.control.TextArea
        SigFreField_2         matlab.ui.control.NumericEditField
        SigFreField_3         matlab.ui.control.NumericEditField
        SigFreField_4         matlab.ui.control.NumericEditField
    end

    
    properties (Access = public)
        
        TestMode = 0;
        FilePath = 'filepath'; % Description
        srv = 200e3;
        
        DataFormat = 'double';
        FFTPoint = 1024;
        breakFlag = 0;
        sF = 'image1.png'
        ConsoleValue = {};
    end
    
    methods (Access = public)
        
        function aDUFPo = UILoc(app)
            ScreenSize = get(0,'ScreenSize');
            FigureSize = [100, 100, 1394, 848];
            FigurePosition_left =  0.5 * (ScreenSize(3)  - FigureSize(3));
            FigurePosition_bottom = 0.72 * (ScreenSize(4) - FigureSize(2) - FigureSize(4));
            app.SOPUIFigure.Position = [FigurePosition_left, FigurePosition_bottom, FigureSize(3), FigureSize(4)];
            aDUFPo = [FigurePosition_left, FigurePosition_bottom, FigureSize(3), FigureSize(4)];
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if app.TestMode == 1
                app.showFilePath.Value = 'G:\MatTemp\DemoGUI\fskpncode.bin';
            else
                app.showFilePath.FontColor = [0.65, 0.65, 0.65];
                app.showFilePath.Value = app.FilePath;
                app.showFilePath.FontAngle = 'italic';
            end
            %%--- 控制窗口位置居中 ---%%
            UILoc(app);
        end

        % Button pushed function: selectFile
        function getFilePath(app, event)
            FilterSpec = '*.bin';
            [FileName, PathName] = uigetfile(FilterSpec);
            app.FilePath = fullfile(PathName, FileName);
            app.showFilePath.Value = fullfile(PathName, FileName);
            app.showFilePath.FontColor = [0, 0, 0];
            app.showFilePath.FontAngle = 'normal';
        end

        % Value changed function: SampleRate, unit
        function SampleRateUnitValueChanged(app, event)
            value = app.unit.Value;
            switch value
                case {'1000', '1', '1000000'}
                    value = str2double(value);
                    app.srv = app.SampleRate.Value;
                    app.srv = app.srv * value;
            end
        end

        % Value changed function: TimeUnitselect
        function TimeUnitselectValueChanged(app, event)
            value = app.TimeUnitselect.Value;
            switch value
                case {'    秒/s', '   毫秒/ms', '   微秒/us'}
                    value = deblank(value);
                    value = regexprep(value, '^.*/', '时间 / ');
                    xlabel(app.TimePlot, value);
            end
        end

        % Value changed function: selectDataFormat
        function selectDataFormatValueChanged(app, event)
            value = app.selectDataFormat.Value;
            app.DataFormat = value;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            %% --- Console Update --- %%
            cellen = length(app.ConsoleValue);
            app.ConsoleValue(cellen + 1) = {'>>>  User Stop'};
            app.ConsoleEditField.Value = app.ConsoleValue;
            %% --- Button State Changes --- %%
            app.StartButton.Enable = 'on';
            app.StartButton.Visible = 'on';
            app.StopButton.Enable = 'off';
            app.StopButton.Visible = 'off';
            app.ZoomIn.Enable = 'on';
            app.SaveButton.Enable = 'on';
            
            %% --- Terminate Flag --- %%
            app.breakFlag = 1;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %% --- Console Update --- %%
            app.ConsoleValue = {'>>>  Begin  '; app.showFilePath.Value; ''};
            app.ConsoleEditField.Value = app.ConsoleValue;
            %% --- Button State Changes --- %%
            app.StopButton.Enable = 'on';
            app.StopButton.Visible = 'on';
            app.StartButton.Visible = 'off';
            app.StartButton.Enable = 'off';
            app.ZoomIn.Enable = 'off';
            app.SaveButton.Enable = 'off';
            
            %% --- Main --- %%
            GUImain;
        end

        % Value changed function: ZoomIn
        function ZoomInValueChanged(app, event)
            value = app.ZoomIn.Value;
            if value
                pan(app.TimePlot,'on');
                zoom(app.TimePlot,'on');
                pan(app.FrePlot,'on');
                zoom(app.FrePlot,'on');
            else
                pan(app.TimePlot,'off');
                zoom(app.TimePlot,'off');
                pan(app.FrePlot,'off');
                zoom(app.FrePlot,'off');
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            ScreenCapture;
            app.ZoomIn.Enable = 'on';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SOPUIFigure = uifigure;
            app.SOPUIFigure.Name = 'SOP';
            app.InputLabel = uilabel(app.SOPUIFigure);
            app.InputLabel.Text = 'Input';
            app.FilePathLabel = uilabel(app.SOPUIFigure);
            app.FilePathLabel.Text = '文件路径';
            app.showFilePath = uieditfield(app.SOPUIFigure, 'text');
            app.selectFile = uibutton(app.SOPUIFigure, 'push');
            app.selectFile.ButtonPushedFcn = createCallbackFcn(app, @getFilePath, true);
            app.selectFile.Text = '...';
            app.SignalPara = uilabel(app.SOPUIFigure);
            app.SignalPara.Text = '信号参数';
            app.unit = uidropdown(app.SOPUIFigure);
            app.unit.Items = {'      kHz', '       Hz', '     MHz'};
            app.unit.ItemsData = {'1000', '1', '1000000'};
            app.unit.ValueChangedFcn = createCallbackFcn(app, @SampleRateUnitValueChanged, true);
            app.unit.Value = '1000';
            app.PlotPara = uilabel(app.SOPUIFigure);
            app.PlotPara.Text = '绘图参数';
            app.TimeUnitselect = uidropdown(app.SOPUIFigure);
            app.TimeUnitselect.Items = {'      秒/s', '  毫秒/ms', '  微秒/us'};
            app.TimeUnitselect.ItemsData = {'    秒/s', '   毫秒/ms', '   微秒/us'};
            app.TimeUnitselect.ValueChangedFcn = createCallbackFcn(app, @TimeUnitselectValueChanged, true);
            app.TimeUnitselect.Value = '   毫秒/ms';
            app.FreUint = uilabel(app.SOPUIFigure);
            app.FreUint.Text = '频率单位';
            app.Timeunit = uilabel(app.SOPUIFigure);
            app.Timeunit.Text = '时间单位';
            app.PlotLabel = uilabel(app.SOPUIFigure);
            app.PlotLabel.Text = 'Plot';
            app.TimePlot = uiaxes(app.SOPUIFigure);
            title(app.TimePlot, '时域波形')
            xlabel(app.TimePlot, '时间 / s')
            ylabel(app.TimePlot, '幅度')
            app.FrePlot = uiaxes(app.SOPUIFigure);
            title(app.FrePlot, '频域波形')
            xlabel(app.FrePlot, '频率 / Hz')
            ylabel(app.FrePlot, '幅度')
            app.OutputLabel = uilabel(app.SOPUIFigure);
            app.OutputLabel.Text = 'Output';
            app.Modulation = uilabel(app.SOPUIFigure);
            app.Modulation.Text = '调制方式';
            app.SignalFre = uilabel(app.SOPUIFigure);
            app.SignalFre.Text = '信号频率';
            app.SymbolRate = uilabel(app.SOPUIFigure);
            app.SymbolRate.Text = '码元速率';
            app.SpreadingCode = uilabel(app.SOPUIFigure);
            app.SpreadingCode.Text = '扩频码';
            app.SampleRateLabel = uilabel(app.SOPUIFigure);
            app.SampleRateLabel.Text = '采样频率';
            app.SampleRate = uieditfield(app.SOPUIFigure, 'numeric');
            app.SampleRate.RoundFractionalValues = 'on';
            app.SampleRate.ValueChangedFcn = createCallbackFcn(app, @SampleRateUnitValueChanged, true);
            app.SampleRate.Value = 200;
            app.FFTEditFieldLabel = uilabel(app.SOPUIFigure);
            app.FFTEditFieldLabel.Text = 'FFT 点数';
            app.inputFFTPoint = uieditfield(app.SOPUIFigure, 'numeric');
            app.inputFFTPoint.Limits = [0 Inf];
            app.inputFFTPoint.RoundFractionalValues = 'on';
            app.inputFFTPoint.ValueDisplayFormat = '%.0f';
            app.inputFFTPoint.Value = 1024;
            app.selectDataFormat = uidropdown(app.SOPUIFigure);
            app.selectDataFormat.Items = {'  double', '    int16'};
            app.selectDataFormat.ItemsData = {'double', 'int16'};
            app.selectDataFormat.Editable = 'on';
            app.selectDataFormat.ValueChangedFcn = createCallbackFcn(app, @selectDataFormatValueChanged, true);
            app.selectDataFormat.Value = 'double';
            app.DataFormatLabel = uilabel(app.SOPUIFigure);
            app.DataFormatLabel.Text = '数据格式';
            app.StopButton = uibutton(app.SOPUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'stop.png';
            app.StopButton.Visible = 'off';
            app.StopButton.Text = '';
            app.StartButton = uibutton(app.SOPUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = 'start.png';
            app.StartButton.Text = '';
            app.ButtonGroup = uibuttongroup(app.SOPUIFigure);
            app.ZoomIn = uibutton(app.ButtonGroup, 'state');
            app.ZoomIn.ValueChangedFcn = createCallbackFcn(app, @ZoomInValueChanged, true);
            app.ZoomIn.Enable = 'off';
            app.ZoomIn.Icon = 'ZoomIn.png';
            app.ZoomIn.Text = '';
            app.SaveButton = uibutton(app.ButtonGroup, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'save.png';
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = '';
            app.SymbolRateField = uieditfield(app.SOPUIFigure, 'numeric');
            app.SymbolRateField.Limits = [0 Inf];
            app.SymbolRateField.Editable = 'off';
            app.SymbolRateUnit = uilabel(app.SOPUIFigure);
            app.SymbolRateUnit.Text = 'bps';
            app.SigFreField = uieditfield(app.SOPUIFigure, 'numeric');
            app.SigFreField.Limits = [0 Inf];
            app.SigFreField.Editable = 'off';
            app.SigFreUnit = uilabel(app.SOPUIFigure);
            app.SigFreUnit.Text = 'kHz';
            app.ModulationText = uilabel(app.SOPUIFigure);
            app.ModulationText.Text = 'NULL';
            app.SpreadingCodePlotAxe = uiaxes(app.SOPUIFigure);
            title(app.SpreadingCodePlotAxe, 'Cycle')
            app.SpreadingCodePlotAxe.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.SpreadingCodePlotAxe.XTickLabel = '';
            app.SpreadingCodePlotAxe.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.SpreadingCodePlotAxe.YTickLabel = '';
            app.ConsoleLabel = uilabel(app.SOPUIFigure);
            app.ConsoleLabel.Text = 'Console';
            app.SymbolCycleLabel = uilabel(app.SOPUIFigure);
            app.SymbolCycleLabel.Text = '码周期';
            app.SymbolCycleEditField = uieditfield(app.SOPUIFigure, 'numeric');
            app.SymbolCycleEditField.Limits = [0 Inf];
            app.SymbolCycleEditField.Editable = 'off';
            app.PNCodeText = uitextarea(app.SOPUIFigure);
            app.PNCodeText.Editable = 'off';
            app.ConsoleEditField = uitextarea(app.SOPUIFigure);
            app.ConsoleEditField.Editable = 'off';
            app.SigFreField_2 = uieditfield(app.SOPUIFigure, 'numeric');
            app.SigFreField_2.Limits = [0 Inf];
            app.SigFreField_2.Editable = 'off';
            app.SigFreField_2.Visible = 'off';
            app.SigFreField_3 = uieditfield(app.SOPUIFigure, 'numeric');
            app.SigFreField_3.Limits = [0 Inf];
            app.SigFreField_3.Editable = 'off';
            app.SigFreField_3.Visible = 'off';
            app.SigFreField_4 = uieditfield(app.SOPUIFigure, 'numeric');
            app.SigFreField_4.Limits = [0 Inf];
            app.SigFreField_4.Editable = 'off';
            app.SigFreField_4.Visible = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DemoGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SOPUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SOPUIFigure)
        end
    end
end